classdef CalibGUI_001 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxesRaw                    matlab.ui.control.UIAxes
        UIAxesProcessed              matlab.ui.control.UIAxes
        UIAxesPupil                  matlab.ui.control.UIAxes
        RotationAngleLabel           matlab.ui.control.Label
        RotAngle                     matlab.ui.control.Spinner
        GUIStagePanel                matlab.ui.container.Panel
        LoadRawImageLampLabel        matlab.ui.control.Label
        LoadRawImageLamp             matlab.ui.control.Lamp
        FindCirclesLampLabel         matlab.ui.control.Label
        FindCirclesLamp              matlab.ui.control.Lamp
        ChoosePupilLampLabel         matlab.ui.control.Label
        ChoosePupilLamp              matlab.ui.control.Lamp
        RotatePupilLampLabel         matlab.ui.control.Label
        RotatePupilLamp              matlab.ui.control.Lamp
        ReplacePupilLampLabel        matlab.ui.control.Label
        ReplacePupilLamp             matlab.ui.control.Lamp
        ExportImageLampLabel         matlab.ui.control.Label
        ExportImageLamp              matlab.ui.control.Lamp
        FindingCirclesPanel          matlab.ui.container.Panel
        RadiusLowEditFieldLabel      matlab.ui.control.Label
        RadiusLowEditField           matlab.ui.control.NumericEditField
        RadiusHighEditFieldLabel     matlab.ui.control.Label
        RadiusHighEditField          matlab.ui.control.NumericEditField
        Sensitivity091Label          matlab.ui.control.Label
        SensitivityEditField         matlab.ui.control.NumericEditField
        FindButton                   matlab.ui.control.Button
        ChooseCurrentPupilPanel      matlab.ui.container.Panel
        CurrentP1                    matlab.ui.control.Button
        CurrentP2                    matlab.ui.control.Button
        CurrentP3                    matlab.ui.control.Button
        CurrentP4                    matlab.ui.control.Button
        CurrentP5                    matlab.ui.control.Button
        CurrentP6                    matlab.ui.control.Button
        CurrentP7                    matlab.ui.control.Button
        ChooseDestinationPupilPanel  matlab.ui.container.Panel
        DestP1                       matlab.ui.control.Button
        DestP2                       matlab.ui.control.Button
        DestP3                       matlab.ui.control.Button
        DestP4                       matlab.ui.control.Button
        DestP5                       matlab.ui.control.Button
        DestP6                       matlab.ui.control.Button
        DestP7                       matlab.ui.control.Button
        ExportButton                 matlab.ui.control.Button
        ImportImageButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        RawIm % Description
        Centers
        Radii
        PupilBank % Description
    end
    
    methods (Access = private)
        
        function  UpdateRawAx(app)
            
            imshow(app.RawIm,'Parent',app.UIAxesRaw);
            
        end
    end
    

    methods (Access = private)

        % Button pushed function: ImportImageButton
        function ImportImageButtonPushed(app, event)
            if (find(ls('..\DISCO_Raw.png')))
                raw2 = rgb2gray(imread('..\DISCO_Raw.png'));
            else
                disp('Image not found in file tree, try again \n');
                raw2 = [];
            end

            a = size(raw2);
            b = diff(a)/2;
            raw2 = raw2(:, b+1:(max(a)- b)); % assumes Horiz > Vert lengths
            
            app.RawIm = raw2;
            UpdateRawAx(app);
        end

        % Button pushed function: FindButton
        function FindButtonPushed(app, event)
            
            %[centers, radii, h] = findPupil(ax_main,raw2,[172 183],0.995);

            [app.Centers, app.Radii] = imfindcircles(app.UIAxesRaw, [app.RadiusLowEditField.Value app.RadiusHighEditField.Value],'Sensitivity',app.SensitivityEditField.Value);


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxesRaw = uiaxes(app.UIFigure);
            title(app.UIAxesRaw, 'Raw Image')
            xlabel(app.UIAxesRaw, '')
            ylabel(app.UIAxesRaw, 'Y')
            app.UIAxesProcessed = uiaxes(app.UIFigure);
            title(app.UIAxesProcessed, 'Processed Image')
            xlabel(app.UIAxesProcessed, 'X')
            ylabel(app.UIAxesProcessed, 'Y')
            app.UIAxesPupil = uiaxes(app.UIFigure);
            title(app.UIAxesPupil, 'Sub Pupil')
            xlabel(app.UIAxesPupil, 'X')
            ylabel(app.UIAxesPupil, 'Y')
            app.RotationAngleLabel = uilabel(app.UIFigure);
            app.RotationAngleLabel.Text = {'Rotation'; 'Angle'};
            app.RotAngle = uispinner(app.UIFigure);
            app.RotAngle.Limits = [-180 180];
            app.GUIStagePanel = uipanel(app.UIFigure);
            app.GUIStagePanel.Title = 'GUI Stage';
            app.LoadRawImageLampLabel = uilabel(app.GUIStagePanel);
            app.LoadRawImageLampLabel.Text = 'Load Raw Image';
            app.LoadRawImageLamp = uilamp(app.GUIStagePanel);
            app.FindCirclesLampLabel = uilabel(app.GUIStagePanel);
            app.FindCirclesLampLabel.Text = 'Find Circles';
            app.FindCirclesLamp = uilamp(app.GUIStagePanel);
            app.ChoosePupilLampLabel = uilabel(app.GUIStagePanel);
            app.ChoosePupilLampLabel.Text = 'Choose Pupil';
            app.ChoosePupilLamp = uilamp(app.GUIStagePanel);
            app.RotatePupilLampLabel = uilabel(app.GUIStagePanel);
            app.RotatePupilLampLabel.Text = 'Rotate Pupil';
            app.RotatePupilLamp = uilamp(app.GUIStagePanel);
            app.ReplacePupilLampLabel = uilabel(app.GUIStagePanel);
            app.ReplacePupilLampLabel.Text = 'Replace Pupil';
            app.ReplacePupilLamp = uilamp(app.GUIStagePanel);
            app.ExportImageLampLabel = uilabel(app.GUIStagePanel);
            app.ExportImageLampLabel.Text = 'Export Image';
            app.ExportImageLamp = uilamp(app.GUIStagePanel);
            app.FindingCirclesPanel = uipanel(app.UIFigure);
            app.FindingCirclesPanel.Title = 'Finding Circles';
            app.RadiusLowEditFieldLabel = uilabel(app.FindingCirclesPanel);
            app.RadiusLowEditFieldLabel.Text = 'Radius Low';
            app.RadiusLowEditField = uieditfield(app.FindingCirclesPanel, 'numeric');
            app.RadiusLowEditField.Limits = [0 Inf];
            app.RadiusLowEditField.Value = 172;
            app.RadiusHighEditFieldLabel = uilabel(app.FindingCirclesPanel);
            app.RadiusHighEditFieldLabel.Text = 'Radius High';
            app.RadiusHighEditField = uieditfield(app.FindingCirclesPanel, 'numeric');
            app.RadiusHighEditField.Value = 183;
            app.Sensitivity091Label = uilabel(app.FindingCirclesPanel);
            app.Sensitivity091Label.Text = 'Sensitivity [0.9 -1]';
            app.SensitivityEditField = uieditfield(app.FindingCirclesPanel, 'numeric');
            app.SensitivityEditField.Limits = [0 1];
            app.SensitivityEditField.Value = 0.995;
            app.FindButton = uibutton(app.FindingCirclesPanel, 'push');
            app.FindButton.ButtonPushedFcn = createCallbackFcn(app, @FindButtonPushed, true);
            app.FindButton.Text = 'Find';
            app.ChooseCurrentPupilPanel = uipanel(app.UIFigure);
            app.ChooseCurrentPupilPanel.Title = 'Choose Current Pupil';
            app.CurrentP1 = uibutton(app.ChooseCurrentPupilPanel, 'push');
            app.CurrentP1.Text = '1';
            app.CurrentP2 = uibutton(app.ChooseCurrentPupilPanel, 'push');
            app.CurrentP2.Text = '2';
            app.CurrentP3 = uibutton(app.ChooseCurrentPupilPanel, 'push');
            app.CurrentP3.Text = '3';
            app.CurrentP4 = uibutton(app.ChooseCurrentPupilPanel, 'push');
            app.CurrentP4.Text = '4';
            app.CurrentP5 = uibutton(app.ChooseCurrentPupilPanel, 'push');
            app.CurrentP5.Text = '5';
            app.CurrentP6 = uibutton(app.ChooseCurrentPupilPanel, 'push');
            app.CurrentP6.Text = '6';
            app.CurrentP7 = uibutton(app.ChooseCurrentPupilPanel, 'push');
            app.CurrentP7.Text = '7';
            app.ChooseDestinationPupilPanel = uipanel(app.UIFigure);
            app.ChooseDestinationPupilPanel.Title = 'Choose Destination Pupil';
            app.DestP1 = uibutton(app.ChooseDestinationPupilPanel, 'push');
            app.DestP1.Text = '1';
            app.DestP2 = uibutton(app.ChooseDestinationPupilPanel, 'push');
            app.DestP2.Text = '2';
            app.DestP3 = uibutton(app.ChooseDestinationPupilPanel, 'push');
            app.DestP3.Text = '3';
            app.DestP4 = uibutton(app.ChooseDestinationPupilPanel, 'push');
            app.DestP4.Text = '4';
            app.DestP5 = uibutton(app.ChooseDestinationPupilPanel, 'push');
            app.DestP5.Text = '5';
            app.DestP6 = uibutton(app.ChooseDestinationPupilPanel, 'push');
            app.DestP6.Text = '6';
            app.DestP7 = uibutton(app.ChooseDestinationPupilPanel, 'push');
            app.DestP7.Text = '7';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.Text = 'Export';
            app.ImportImageButton = uibutton(app.UIFigure, 'push');
            app.ImportImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImportImageButtonPushed, true);
            app.ImportImageButton.Text = 'Import Image';
        end
    end

    methods (Access = public)

        % Construct app
        function app = CalibGUI_001

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end