classdef ImportPupil < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ImportImageButton        matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
        FindPupilPanel           matlab.ui.container.Panel
        RadiusLowSpinnerLabel    matlab.ui.control.Label
        RadiusLowSpinner         matlab.ui.control.Spinner
        RadiusHighSpinnerLabel   matlab.ui.control.Label
        RadiusHighSpinner        matlab.ui.control.Spinner
        SensitivitySpinnerLabel  matlab.ui.control.Label
        SensitivitySpinner       matlab.ui.control.Spinner
        FindPupilButton          matlab.ui.control.Button
        MessagesTextAreaLabel    matlab.ui.control.Label
        MessagesTextArea         matlab.ui.control.TextArea
        TrimButton               matlab.ui.control.Button
        ExportToMainButton       matlab.ui.control.Button
        LabelPupilsButton        matlab.ui.control.Button
        PopulateListButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp; % Main App Object
        RawIm       % Raw image from fibers
        Centers;    % Centers of all the pupils
        Radii;      % Radi of all the pupils
        PupilBank;  % Vector of pupil object
        initialFind % Flag: first time running FindPupil?
        h_visCirc   % viscircle handle, used for removing circle labels
        BoundingBox % square size of the largest identified pupil.
        MaxR        % maximum radius found across all pupils
        
        
    end
    
    properties (Access = public)
        FindParams  % Parameters for imfindcircles [radius low, radius High, Sensitivity]
        MaxRadius   % Maximum radius across all sub-pupils
    end
    
    methods (Access = private)
        
        function updateImPlot(app)
            imshow(app.RawIm,'Parent',app.UIAxes);
        end
        
        function pupilIm = IsolatePupil(app,pupil_num)
            
            p_mask = mask(app.RawIm,app.Centers(pupil_num,:),app.Radii(pupil_num)); % mask the individual sub pupil inside the full image
            isolated_image = uint16(p_mask) .* app.RawIm;   % show the pupil within the fulle image
            
            trimmed_im = isolated_image;    % prepare the image to be trimmed down to size
            trimmed_im(~any(isolated_image,2),:) = []; % deletes any 0 column
            trimmed_im(:,~any(isolated_image,1)) = []; % deletes any 0 row
            
            % Re-Pad trimmed_im to match Max Box Dimensions (important for substitution)
            padding = app.BoundingBox - size(trimmed_im);
            if padding(1)
                trimmed_im = [zeros(padding(1),size(trimmed_im,2));trimmed_im]; % pad rows
            end
            if padding(2)
                trimmed_im = [trimmed_im , zeros(size(trimmed_im,1),padding(2))]; %pad columns
            end
            pupilIm = trimmed_im;
        end % end Isolate Pupil
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, pupilBank)
            app.CallingApp = mainApp;
            app.PupilBank = pupilBank;
            
            app.FindPupilPanel.Visible = 0;
            app.TrimButton.Enable = 0;
            app.initialFind = 1;
        end

        % Button pushed function: ImportImageButton
        function ImportImageButtonPushed(app, event)
            
            [imname, impath] = uigetfile('*.*','Choose Location of Raw Image', '..\sample_files\');
            if(impath)
                rawIm = imread([impath imname]);
                if(length(size(rawIm)) == 3)
                    rawIm = rgb2gray(imread([impath imname]));
                end
                app.RawIm = rawIm;
                updateImPlot(app);
                clear rawIm;
                app.TrimButton.Enable = 1;
                
            else
                uialert(app.UIFigure,'No File Selected. Please select File','File Selection Error')
                return
            end
            
        end

        % Button pushed function: TrimButton
        function TrimButtonPushed(app, event)
%             a = size(app.RawIm);
%             b = diff(a)/2;
%             app.RawIm = app.RawIm(:, b+1:(max(a)- b)); % assumes Horiz > Vert lengths
%             updateImPlot(app);


            app.FindPupilPanel.Visible = 1;
        end

        % Button pushed function: FindPupilButton
        function FindPupilButtonPushed(app, event)
            if(~app.initialFind)
                delete(app.h_visCirc);
            end
            im = app.RawIm;
            radiusLow = app.RadiusLowSpinner.Value;
            radiusHigh = app.RadiusHighSpinner.Value;
            sensitivity= app.SensitivitySpinner.Value;
            
            app.MessagesTextArea.Value = 'Finding Circles...';
            
            [centers, radii] = imfindcircles(im, [radiusLow radiusHigh],'Sensitivity',sensitivity);
            app.MessagesTextArea.Value = 'Found Circles!';
            app.h_visCirc = appviscircles(app.UIAxes,centers,radii);
            app.Centers = centers;
            app.Radii = radii;
            
            app.initialFind =0;
            app.LabelPupilsButton.Enable = 1;
            
            %% Check Dimensions of Largest Bounding Box
            
            bbox_r = round([app.Centers(:,1) - app.Radii(:),app.Centers(:,1) + app.Radii(:)]);
            bbox_c = round([app.Centers(:,2) - app.Radii(:),app.Centers(:,2) + app.Radii(:)]);
            boxLengths = [diff(bbox_r,1,2),diff(bbox_c,1,2)];
            
            app.MaxR = max(round(app.Radii));
            app.BoundingBox= max(boxLengths(:,1))*ones(1,2);
            app.FindParams = [radiusLow, radiusHigh, sensitivity];
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: LabelPupilsButton
        function LabelPupilsButtonPushed(app, event)
            strIdx = 1:7;
            h_strIdx = text(app.UIAxes,app.Centers(:,1),app.Centers(:,2),num2cell(strIdx(1,:)),'color','red');
            
            %delete(h_strIdx); % command if you want to delete text label
            app.PopulateListButton.Enable = 1;
        end

        % Button pushed function: PopulateListButton
        function PopulateListButtonPushed(app, event)
            
            app.PupilBank = [];
            pupil = Pupil();
            for i =1:length(app.Radii)
                pupil.ID = i;
                pupil.Center = round(app.Centers(i,:));
                pupil.Radius = round(app.Radii(i));
                pupil.Rotation = 0;
                pupil.Translation = [0,0];
                pupil.Mask = [];
                pupil.Im = IsolatePupil(app,i);
                
                app.PupilBank = [app.PupilBank pupil];
                clear pupil ;pupil = Pupil();
                
                
            end
            
            
        end

        % Button pushed function: ExportToMainButton
        function ExportToMainButtonPushed(app, event)
            
            imshow(0.*app.RawIm,'parent',app.CallingApp.IPAxes); % Plot a blank canvas for the final Image
            app.CallingApp.PupilBank = app.PupilBank;            % Transfer the pupil Bank
            app.CallingApp.NewBank = app.PupilBank;              % Instantiate New Bank (not used)
            app.CallingApp.IPImage = 0.*app.RawIm;               % Transfer the In-Progress Image
            app.CallingApp.CurrentPupil = 1;                     % set starting pupil index
            app.CallingApp.MaxR = app.MaxR;                      % Transfer Max Radius across pupilBank
            UpdateCurrent(app.CallingApp,0.*app.PupilBank(1).Im);% Plot the sub-pupil
            app.CallingApp.contFlag = true;                      % Flip the Continue Flag (not used)
            
            pb = app.PupilBank;                                  % Prepare to Save GUI output
            save('../Temp/IP_PupilBank.mat', 'pb');
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ImportImageButton = uibutton(app.UIFigure, 'push');
            app.ImportImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImportImageButtonPushed, true);
            app.ImportImageButton.Text = 'Import Image';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.FindPupilPanel = uipanel(app.UIFigure);
            app.FindPupilPanel.Title = 'Find Pupil';
            app.RadiusLowSpinnerLabel = uilabel(app.FindPupilPanel);
            app.RadiusLowSpinnerLabel.Text = 'Radius Low';
            app.RadiusLowSpinner = uispinner(app.FindPupilPanel);
            app.RadiusLowSpinner.Step = 0.005;
            app.RadiusLowSpinner.Limits = [0 Inf];
            app.RadiusLowSpinner.Value = 172;
            app.RadiusHighSpinnerLabel = uilabel(app.FindPupilPanel);
            app.RadiusHighSpinnerLabel.Text = 'Radius High';
            app.RadiusHighSpinner = uispinner(app.FindPupilPanel);
            app.RadiusHighSpinner.Step = 0.005;
            app.RadiusHighSpinner.Limits = [0 Inf];
            app.RadiusHighSpinner.Value = 183;
            app.SensitivitySpinnerLabel = uilabel(app.FindPupilPanel);
            app.SensitivitySpinnerLabel.Text = 'Sensitivity';
            app.SensitivitySpinner = uispinner(app.FindPupilPanel);
            app.SensitivitySpinner.Step = 0.005;
            app.SensitivitySpinner.Limits = [0 1];
            app.FindPupilButton = uibutton(app.FindPupilPanel, 'push');
            app.FindPupilButton.ButtonPushedFcn = createCallbackFcn(app, @FindPupilButtonPushed, true);
            app.FindPupilButton.Text = 'Find Pupil';
            app.MessagesTextAreaLabel = uilabel(app.UIFigure);
            app.MessagesTextAreaLabel.Text = 'Messages';
            app.MessagesTextArea = uitextarea(app.UIFigure);
            app.TrimButton = uibutton(app.UIFigure, 'push');
            app.TrimButton.ButtonPushedFcn = createCallbackFcn(app, @TrimButtonPushed, true);
            app.TrimButton.Enable = 'off';
            app.TrimButton.Text = 'Trim';
            app.ExportToMainButton = uibutton(app.UIFigure, 'push');
            app.ExportToMainButton.ButtonPushedFcn = createCallbackFcn(app, @ExportToMainButtonPushed, true);
            app.ExportToMainButton.Text = {'Export To'; 'Main'};
            app.LabelPupilsButton = uibutton(app.UIFigure, 'push');
            app.LabelPupilsButton.ButtonPushedFcn = createCallbackFcn(app, @LabelPupilsButtonPushed, true);
            app.LabelPupilsButton.Enable = 'off';
            app.LabelPupilsButton.Text = 'Label Pupils';
            app.PopulateListButton = uibutton(app.UIFigure, 'push');
            app.PopulateListButton.ButtonPushedFcn = createCallbackFcn(app, @PopulateListButtonPushed, true);
            app.PopulateListButton.Enable = 'off';
            app.PopulateListButton.Text = 'Populate List';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ImportPupil(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end