classdef ManipulatePupil < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        CPAxes                      matlab.ui.control.UIAxes
        IPAxes                      matlab.ui.control.UIAxes
        PupilDropDownLabel          matlab.ui.control.Label
        PupilDropDown               matlab.ui.control.DropDown
        RotationPanel               matlab.ui.container.Panel
        CWButton                    matlab.ui.control.Button
        CCWButton                   matlab.ui.control.Button
        AngleEditFieldLabel         matlab.ui.control.Label
        AngleEditField              matlab.ui.control.NumericEditField
        AngleLockButton             matlab.ui.control.Button
        StepSizeEditFieldLabel      matlab.ui.control.Label
        StepSizeEditField           matlab.ui.control.NumericEditField
        PupilDestinationPanel       matlab.ui.container.Panel
        CurrentPupilEditFieldLabel  matlab.ui.control.Label
        CurrentPupilEditField       matlab.ui.control.EditField
        DestinationDropDownLabel    matlab.ui.control.Label
        DestinationDropDown         matlab.ui.control.DropDown
        ReplaceButton               matlab.ui.control.Button
        LockLayoutButton            matlab.ui.control.Button
        LoadPupilsButton            matlab.ui.control.Button
        PupilTrackpadPanel          matlab.ui.container.Panel
        TransLeft                   matlab.ui.control.Button
        TransDown                   matlab.ui.control.Button
        TransRight                  matlab.ui.control.Button
        TransUp                     matlab.ui.control.Button
        LockTranslationButton       matlab.ui.control.Button
        FastRight                   matlab.ui.control.Button
        FastLeft                    matlab.ui.control.Button
        FastUp                      matlab.ui.control.Button
        FastDown                    matlab.ui.control.Button
        SaveFinalImageButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        ImportPupilApp
        rotIm % Description
        AngleStep % Description
        
    end
    
    properties (Access = public)
        IPImage % Description
        MaxR    % maximum radius across all pupils in PupilBank
        PupilBank % Description
        CurrentPupil % Int Index of current pupil
        NewBank
        contFlag
        caxisLim
        tifAxisLim
    end
    
    methods (Access = private)
        
        
        function RotateSubPupil(app, degRot)
            
            app.rotIm = imrotate(app.PupilBank(app.CurrentPupil).Im,degRot,'nearest','crop');
            UpdateCurrent(app,app.rotIm); % update CurrentAxes
        end
        
        function RenderPupil_on(app)
            
            max_r = app.MaxR;
            idx = app.CurrentPupil;
            rot = app.PupilBank(idx).Rotation;
            
            % lookup and rotate: pupil.Im
            im = imrotate(app.PupilBank(idx).Im, rot, 'nearest','crop');
            
            % lookup: Translation [dRow dCol]
            % execute: Pupil.Center + Pupil.Translation
            decenter = app.PupilBank(idx).Center + app.PupilBank(idx).Translation;
            
            %Call subPupil Mask
            sub_mask = app.PupilBank(app.CurrentPupil).Mask;
            
            % Calculate corners to substitute box
            subpts = calcEndPts_GUI(decenter,max_r); % calculate substitution coordinates
            
            % Replace Image to In-progress image
            sub_im = replacePupil(app.IPImage,im,sub_mask,subpts);
            
            app.IPImage = sub_im;
            UpdateIP(app,app.IPImage);
        end
        
        function RenderPupil_off(app)
            max_r = app.MaxR;
            idx = app.CurrentPupil;
            
            % Black out current rendering
            % im = nan.*app.PupilBank(idx).Im;
            im = nan(size(app.PupilBank(idx).Im));
            decenter = app.PupilBank(idx).Center + app.PupilBank(idx).Translation;
            sub_mask = app.PupilBank(app.CurrentPupil).Mask;
            subpts = calcEndPts_GUI(decenter,max_r);
            sub_im = replacePupil(app.IPImage,im,sub_mask,subpts);
            app.IPImage = sub_im;
            UpdateIP(app,app.IPImage);
        end
        
        function Translate(app, Direction, Sign, StepSize)
            % Direction: use '1' for Horizontal, use '2' for Vertical
            % Sign:      use '-1' to increase(Up/R), use '1' to decrease (Down/L)
            % StepSize:  (unsigned) use custom amount to translate by more than 1 px per function call.
            
            idx = app.CurrentPupil;
            % Remove current subpupil from IP_im
            RenderPupil_off(app)
            % Decrement the Column value (move in -x)
            pupil = app.PupilBank(idx);
            pupil.Translation(Direction) = pupil.Translation(Direction) + Sign*StepSize;
            app.PupilBank(idx) = pupil;
            % Render the image
            RenderPupil_on(app)
        end
    end
    
    methods (Access = public)
        
        function UpdateCurrent(app,im)
            imshow(im,'parent',app.CPAxes)
            caxis(app.CPAxes, [0 app.caxisLim]);
        end
        
        function UpdateIP(app,im)
            imshow(im,'parent',app.IPAxes)
            caxis(app.IPAxes, [0 app.caxisLim]);
            if(~app.caxisLim)
                caxis(app.IPAxes, [0 app.tifAxisLim]);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %             load 'myPupilBank.mat'
            %             load 'baseIm.mat'
            %             app.PupilBank = pb;
            %             app.IPImage = raw2;
            %
            %             rads = zeros(size(pb));
            %
            %             ceil(max([pb.Radius]))
            %             app.MaxR = ceil(max(rads));
            %             app.CurrentPupil = 1;
            %             UpdateCurrent(app,app.PupilBank(app.CurrentPupil).Im)
            %             UpdateIP(app,0*app.IPImage)
            app.AngleStep = app.StepSizeEditField.Value;
            app.tifAxisLim = 0;     % Default Value for tif file-type- axis limit
            app.caxisLim = 0;     % Default Value for axis limit (IP axes)
            
        end

        % Button pushed function: CCWButton
        function CCWButtonPushed(app, event)
            degRot = app.AngleEditField.Value + app.AngleStep;
            app.AngleEditField.Value = app.AngleEditField.Value + app.AngleStep;             % Update angle box
            
            
            if(app.PupilBank(app.CurrentPupil).Subbed)
                idx  = app.CurrentPupil;
                % New Function: RealTimeRotate
                app.PupilBank(idx).Rotation = app.AngleEditField.Value;
                RenderPupil_off(app);
                RenderPupil_on(app);
            end
            RotateSubPupil(app, double(degRot));
        end

        % Button pushed function: CWButton
        function CWButtonPushed(app, event)
            degRot = app.AngleEditField.Value - app.AngleStep;
            app.AngleEditField.Value = app.AngleEditField.Value - app.AngleStep;             % Update angle box
            
            if(app.PupilBank(app.CurrentPupil).Subbed)
                idx  = app.CurrentPupil;
                % im = app.PupilBank(idx).Im;
                % New Function: RealTimeRotate
                app.PupilBank(idx).Rotation = app.AngleEditField.Value;
                RenderPupil_off(app);
                RenderPupil_on(app);
            end
            
            RotateSubPupil(app, double(degRot));
        end

        % Value changed function: AngleEditField
        function AngleEditFieldValueChanged(app, event)
            degRot = app.AngleEditField.Value;
            
            if(app.PupilBank(app.CurrentPupil).Subbed)
                idx  = app.CurrentPupil;
                % New Function: RealTimeRotate
                app.PupilBank(idx).Rotation = app.AngleEditField.Value;
                RenderPupil_off(app);
                RenderPupil_on(app);
            end
            RotateSubPupil(app, double(degRot));
        end

        % Button pushed function: AngleLockButton
        function AngleLockButtonPushed(app, event)
            %blank out user angle input
            %             app.AngleEditField.Editable = 'off';
            % access the current pupil index
            
            % save degreeRot to PupilBank[idx]
            app.PupilBank(app.CurrentPupil).Rotation = app.AngleEditField.Value;
            
            % Replace Image in PupilBank[idx]
%             app.PupilBank(app.CurrentPupil).Im = app.rotIm; % save rotIm
%             to pupilBank % line prevents us from modifying later.. would
%             result in rot(rot(im))
        end

        % Button pushed function: LoadPupilsButton
        function LoadPupilsButtonPushed(app, event)
            %             app.ImportPupilApp = ImportPupil(app, app.PupilBank);
            app.ImportPupilApp = ImportPupil_manual(app, app.PupilBank);
        end

        % Button pushed function: ReplaceButton
        function ReplaceButtonPushed(app, event)
            % Populate Internal variables
            pupil_dest = app.DestinationDropDown.Value;
            center_dest = app.PupilBank(str2num(pupil_dest)).Center(:)'; % Transpose to keep same dimensionality as currentPupil.Center
            decenter = center_dest + app.PupilBank(app.CurrentPupil).Translation;
            max_r = app.MaxR;
            
            sc = size(app.PupilBank(app.CurrentPupil).Im)./2;
            sr = app.PupilBank(app.CurrentPupil).Radius;
            
            sub_mask = mask(app.PupilBank(app.CurrentPupil).Im ,sc(1,:),sr(1));
            app.PupilBank(app.CurrentPupil).Mask = sub_mask;                %Instantiate the Pupil Mask
            
            pupil_rot= double(sub_mask).* double( imrotate(app.PupilBank(app.CurrentPupil).Im, app.PupilBank(app.CurrentPupil).Rotation,'nearest','crop') ) ; % convert to double for rotation
            
            pupil_rot = uint16(pupil_rot);  % revert to uint16 for IP Axes plotting
            
            subpts = calcEndPts_GUI(center_dest,max_r); % calculate substitution coordinates
            
            % Replace Image to In-progress image
            sub_im = replacePupil(app.IPImage,pupil_rot,sub_mask,subpts); % is the full size image with the addition of current sub_pupil.
            
            app.IPImage = uint16(sub_im);
            sub_im = uint16(sub_im);
            
            UpdateIP(app,app.IPImage);
            
            % Track the pupil's translation
            translation = center_dest - app.PupilBank(app.CurrentPupil).Center;
            app.PupilBank(app.CurrentPupil).Translation = translation;
            
            % Flag: Pupil Placed in In-Progress Image
            app.PupilBank(app.CurrentPupil).Subbed = 1;
            
            %% Save sequence to re-order pupils into app.NewBank
            currentPupilObject = app.PupilBank(app.CurrentPupil);
            app.NewBank(pupil_dest).Center = currentPupilObject.Center;
            app.NewBank(pupil_dest).Im = currentPupilObject.Im;
            app.NewBank(pupil_dest).Rotation = currentPupilObject.Rotation;
        end

        % Value changed function: PupilDropDown
        function PupilDropDownValueChanged(app, event)
            value = str2num(app.PupilDropDown.Value);
            app.CurrentPupil = value;                           % change current pupil in pupilBank
            app.AngleEditField.Value = app.PupilBank(app.CurrentPupil).Rotation;
            app.CurrentPupilEditField.Value = num2str(value);   % Update the 'Current Pupil Field' in the Pupil Destination group
            UpdateCurrent(app, ( imrotate(app.PupilBank(app.CurrentPupil).Im, app.PupilBank(app.CurrentPupil).Rotation,'nearest','crop') )); % plot the sub-pupil in the CurrentPupil Axes
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            if(exist('app.ImportPupilApp','var'))
                delete(app.ImportPupilApp)
            end
            delete(app)
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            
            switch key
                case 'a'
                    %go left! (decrease Column)
                    % if (center +- MaxRadius) > Size(Im,1) OR < Size(Im ,2)
                    if(ceil(app.PupilBank(app.CurrentPupil).Center(2)) + ceil(app.PupilBank(app.CurrentPupil).Translation(2)) - ceil(app.MaxR) > 0)
                        Translate(app, 1, -1, 1);
                    else
                        msgbox('Boundary reached. No can do, amigo');
                    end
                case 'd'
                    %go Right!
                    % Pupi.Center(1) and Pupil.Translation(1) record the 'dx' or change in columns
                    if(ceil(app.PupilBank(app.CurrentPupil).Center(1)) + ceil(app.PupilBank(app.CurrentPupil).Translation(1)) + ceil(app.MaxR) < size(app.IPImage,2) )
                        Translate(app, 1, 1, 1);
                    else
                        msgbox('Boundary reached. No can do, amigo');
                    end
                case 'w'
                    %go Up!
                    %                     Translate(app, 2, -1, 1);
                    stepsize = 1;
                    if(ceil(app.PupilBank(app.CurrentPupil).Center(2)) + ceil(app.PupilBank(app.CurrentPupil).Translation(2)) - ceil(app.MaxR) - stepsize > 0)
                        Translate(app, 2, -1, stepsize);
                    else
                        msgbox('Boundary reached. No can do, amigo');
                    end
                case 's'
                    %go Down!
                    Translate(app, 2, 1, 1);
            end
        end

        % Button pushed function: TransLeft
        function TransLeftButtonPushed(app, event)
            %    Translate( E/W or N/S , 1 or -1 , stepSize )
            stepsize = 1;
            
            if(floor(app.PupilBank(app.CurrentPupil).Center(1)) + app.PupilBank(app.CurrentPupil).Translation(1) - ceil(app.MaxR) - stepsize> 0)
                Translate(app, 1, -1, 1);
            else
                msgbox('Boundary reached. No can do, amigo');
            end
        end

        % Button pushed function: SaveFinalImageButton
        function SaveFinalImageButtonPushed(app, event)
            % use of saveDir assumes use of matlab program and not a
            % standalone matlab executable
            
            saveDir = [pwd '\Temp\'];
            
            pb = app.PupilBank;
            FinImMat = app.IPImage; % Conserves the NaNs
            %             FinImMat(isinf(FinImMat)) = NaN;
            FinImMat = double(FinImMat);
            FinImMat(FinImMat == 2^16-1) = NaN;
            
            % ----------------------
            finIm = app.IPImage;
            finIm(isinf(finIm)) = 0; % Convert clipped portions (shown as inf) to zeros
            finIm(isnan(finIm)) = 0; % Conversion of Nans to 0
            finIm = finIm./2^16;    % Map to [0 1]
            % ----------------------
            if ~exist('Temp/','dir')
                mkdir('Temp/')
            end
            imwrite(finIm,[saveDir 'Fin_im.tiff'],'WriteMode','overwrite');
            save([saveDir 'Fin_ImMatrix.mat'],'FinImMat','-mat');
            save([ saveDir 'Fin_PupilBank.mat'], 'pb');
        end

        % Button pushed function: TransUp
        function TransUpButtonPushed(app, event)
            %             Translate(app, 2, 1, 1);
            
            stepsize = 1;
            if(ceil(app.PupilBank(app.CurrentPupil).Center(2)) + ceil(app.PupilBank(app.CurrentPupil).Translation(2)) - ceil(app.MaxR) - stepsize > 0)
                Translate(app, 2, -1, stepsize);
            else
                msgbox('Boundary reached. No can do, amigo');
            end
            
        end

        % Button pushed function: TransRight
        function TransRightButtonPushed(app, event)
            %             Translate(app, 1, 1, 1);
            % Pupi.Center(1) and Pupil.Translation(1) record the 'dx' or change in columns
            stepsize = 1;
            Translate(app, 1, 1, stepsize);
            %             if(ceil(app.PupilBank(app.CurrentPupil).Center(1)) + ceil(app.PupilBank(app.CurrentPupil).Translation(1)) + ceil(app.MaxR) + stepsize < size(app.IPImage,2))
            %                 Translate(app, 1, 1, stepsize);
            %             else
            %                 msgbox('Boundary reached. No can do, amigo');
            %             end
            
        end

        % Button pushed function: TransDown
        function TransDownButtonPushed(app, event)
            %             Translate(app, 2, -1, 1);
            
            stepsize = 1;
            Translate(app, 2, 1, stepsize);
            %             if(ceil(app.PupilBank(app.CurrentPupil).Center(2)) + ceil(app.PupilBank(app.CurrentPupil).Translation(2)) + ceil(app.MaxR) + stepsize < size(app.IPImage,1))
            %                 Translate(app, 2, -1, stepsize);
            %             else
            %                 msgbox('Boundary reached. No can do, amigo');
            %             end
        end

        % Button pushed function: FastRight
        function FastRightButtonPushed(app, event)
            %             Translate(app, 1, 1, 1);
            % Pupi.Center(1) and Pupil.Translation(1) record the 'dx' or change in columns
            
            stepsize = 100;
            Translate(app, 1, 1, stepsize);
            %             if(ceil(app.PupilBank(app.CurrentPupil).Center(1)) + ceil(app.PupilBank(app.CurrentPupil).Translation(1)) + ceil(app.MaxR) + stepsize < 1200)
            %                 Translate(app, 1, 1, stepsize);
            %             else
            %                 msgbox('Boundary reached. No can do, amigo');
            %             end
        end

        % Button pushed function: FastLeft
        function FastLeftButtonPushed(app, event)
            stepsize = 100;
            if(ceil(app.PupilBank(app.CurrentPupil).Center(1)) + ceil(app.PupilBank(app.CurrentPupil).Translation(1)) + ceil(app.MaxR) - stepsize > 0)
                Translate(app, 1, -1, stepsize);
            else
                msgbox('Boundary reached. No can do, amigo');
            end
        end

        % Button pushed function: FastUp
        function FastUpButtonPushed(app, event)
            
            stepsize = 100;
            if(ceil(app.PupilBank(app.CurrentPupil).Center(2)) + ceil(app.PupilBank(app.CurrentPupil).Translation(2)) - ceil(app.MaxR) - stepsize > 0)
                Translate(app, 2, -1, stepsize);
            else
                msgbox('Boundary reached. No can do, amigo');
            end
            
        end

        % Button pushed function: FastDown
        function FastDownButtonPushed(app, event)
            stepsize = 100;
            Translate(app, 2, 1, stepsize);
            %             if(ceil(app.PupilBank(app.CurrentPupil).Center(2)) + ceil(app.PupilBank(app.CurrentPupil).Translation(2)) + ceil(app.MaxR) + stepsize < 1200)
            %                 Translate(app, 2, 1, stepsize);
            %             else
            %                 msgbox('Boundary reached. No can do, amigo');
            %             end
        end

        % Value changed function: StepSizeEditField
        function StepSizeEditFieldValueChanged(app, event)
            app.AngleStep = app.StepSizeEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.CPAxes = uiaxes(app.UIFigure);
            title(app.CPAxes, 'Current Pupil')
            app.CPAxes.XTick = [];
            app.CPAxes.YTick = [];
            app.IPAxes = uiaxes(app.UIFigure);
            title(app.IPAxes, 'In-Progress Image')
            app.IPAxes.XTick = [];
            app.IPAxes.YTick = [];
            app.IPAxes.ZTick = [];
            app.PupilDropDownLabel = uilabel(app.UIFigure);
            app.PupilDropDownLabel.Text = 'Pupil';
            app.PupilDropDown = uidropdown(app.UIFigure);
            app.PupilDropDown.Items = {'Select Pupil', 'Pupil 1', 'Pupil 2', 'Pupil 3', 'Pupil 4', 'Pupil 5', 'Pupil 6', 'Pupil 7'};
            app.PupilDropDown.ItemsData = {'0', '1', '2', '3', '4', '5', '6', '7'};
            app.PupilDropDown.ValueChangedFcn = createCallbackFcn(app, @PupilDropDownValueChanged, true);
            app.PupilDropDown.Value = '0';
            app.RotationPanel = uipanel(app.UIFigure);
            app.RotationPanel.Title = 'Rotation Panel';
            app.CWButton = uibutton(app.RotationPanel, 'push');
            app.CWButton.ButtonPushedFcn = createCallbackFcn(app, @CWButtonPushed, true);
            app.CWButton.Text = 'CW';
            app.CCWButton = uibutton(app.RotationPanel, 'push');
            app.CCWButton.ButtonPushedFcn = createCallbackFcn(app, @CCWButtonPushed, true);
            app.CCWButton.Text = 'CCW';
            app.AngleEditFieldLabel = uilabel(app.RotationPanel);
            app.AngleEditFieldLabel.Text = 'Angle';
            app.AngleEditField = uieditfield(app.RotationPanel, 'numeric');
            app.AngleEditField.ValueChangedFcn = createCallbackFcn(app, @AngleEditFieldValueChanged, true);
            app.AngleLockButton = uibutton(app.RotationPanel, 'push');
            app.AngleLockButton.ButtonPushedFcn = createCallbackFcn(app, @AngleLockButtonPushed, true);
            app.AngleLockButton.Text = 'Angle Lock';
            app.StepSizeEditFieldLabel = uilabel(app.RotationPanel);
            app.StepSizeEditFieldLabel.Text = 'Step Size';
            app.StepSizeEditField = uieditfield(app.RotationPanel, 'numeric');
            app.StepSizeEditField.Limits = [0 Inf];
            app.StepSizeEditField.ValueChangedFcn = createCallbackFcn(app, @StepSizeEditFieldValueChanged, true);
            app.StepSizeEditField.Value = 1;
            app.PupilDestinationPanel = uipanel(app.UIFigure);
            app.PupilDestinationPanel.Title = 'Pupil Destination';
            app.CurrentPupilEditFieldLabel = uilabel(app.PupilDestinationPanel);
            app.CurrentPupilEditFieldLabel.Text = 'Current Pupil';
            app.CurrentPupilEditField = uieditfield(app.PupilDestinationPanel, 'text');
            app.CurrentPupilEditField.Editable = 'off';
            app.DestinationDropDownLabel = uilabel(app.PupilDestinationPanel);
            app.DestinationDropDownLabel.Text = 'Destination';
            app.DestinationDropDown = uidropdown(app.PupilDestinationPanel);
            app.DestinationDropDown.Items = {'Pupil 1', 'Pupil 2', 'Pupil 3', 'Pupil 4', 'Pupil 5', 'Pupil 6', 'Pupil 7'};
            app.DestinationDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7'};
            app.DestinationDropDown.Value = '4';
            app.ReplaceButton = uibutton(app.PupilDestinationPanel, 'push');
            app.ReplaceButton.ButtonPushedFcn = createCallbackFcn(app, @ReplaceButtonPushed, true);
            app.ReplaceButton.Text = 'Replace';
            app.LockLayoutButton = uibutton(app.PupilDestinationPanel, 'push');
            app.LockLayoutButton.Enable = 'off';
            app.LockLayoutButton.Visible = 'off';
            app.LockLayoutButton.Text = 'Lock Layout';
            app.LoadPupilsButton = uibutton(app.UIFigure, 'push');
            app.LoadPupilsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPupilsButtonPushed, true);
            app.LoadPupilsButton.Text = 'Load Pupils';
            app.PupilTrackpadPanel = uipanel(app.UIFigure);
            app.PupilTrackpadPanel.Title = 'Pupil Trackpad';
            app.TransLeft = uibutton(app.PupilTrackpadPanel, 'push');
            app.TransLeft.ButtonPushedFcn = createCallbackFcn(app, @TransLeftButtonPushed, true);
            app.TransLeft.Text = 'A';
            app.TransDown = uibutton(app.PupilTrackpadPanel, 'push');
            app.TransDown.ButtonPushedFcn = createCallbackFcn(app, @TransDownButtonPushed, true);
            app.TransDown.Text = 'S';
            app.TransRight = uibutton(app.PupilTrackpadPanel, 'push');
            app.TransRight.ButtonPushedFcn = createCallbackFcn(app, @TransRightButtonPushed, true);
            app.TransRight.Text = 'D';
            app.TransUp = uibutton(app.PupilTrackpadPanel, 'push');
            app.TransUp.ButtonPushedFcn = createCallbackFcn(app, @TransUpButtonPushed, true);
            app.TransUp.Text = 'W';
            app.LockTranslationButton = uibutton(app.PupilTrackpadPanel, 'push');
            app.LockTranslationButton.Enable = 'off';
            app.LockTranslationButton.Visible = 'off';
            app.LockTranslationButton.Text = 'Lock Translation';
            app.FastRight = uibutton(app.PupilTrackpadPanel, 'push');
            app.FastRight.ButtonPushedFcn = createCallbackFcn(app, @FastRightButtonPushed, true);
            app.FastRight.Text = '>>f';
            app.FastLeft = uibutton(app.PupilTrackpadPanel, 'push');
            app.FastLeft.ButtonPushedFcn = createCallbackFcn(app, @FastLeftButtonPushed, true);
            app.FastLeft.Text = 'z<<';
            app.FastUp = uibutton(app.PupilTrackpadPanel, 'push');
            app.FastUp.ButtonPushedFcn = createCallbackFcn(app, @FastUpButtonPushed, true);
            app.FastUp.Text = {'^'; 'e'};
            app.FastDown = uibutton(app.PupilTrackpadPanel, 'push');
            app.FastDown.ButtonPushedFcn = createCallbackFcn(app, @FastDownButtonPushed, true);
            app.FastDown.Text = {'v'; 'q'};
            app.SaveFinalImageButton = uibutton(app.UIFigure, 'push');
            app.SaveFinalImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFinalImageButtonPushed, true);
            app.SaveFinalImageButton.Text = {'Save Final '; 'Image'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ManipulatePupil

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end