classdef PaddingWindow_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        PaddingWidthEditFieldLabel  matlab.ui.control.Label
        PaddingWidthEditField       matlab.ui.control.NumericEditField
        PadButton                   matlab.ui.control.Button
        SaveButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % Description
        
        Pad
        RawIm % Description
        PadIm % Description
        
    end
    
    properties (Access = public)
        ImOut % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, rawIm, ImOut)
            app.CallingApp = mainApp;
            app.RawIm = rawIm;
            app.ImOut = ImOut;
        end

        % Value changed function: PaddingWidthEditField
        function PaddingWidthEditFieldValueChanged(app, event)
            value = app.PaddingWidthEditField.Value;
            
            app.Pad = value;
            
        end

        % Button pushed function: PadButton
        function PadButtonPushed(app, event)
            rawIm = app.RawIm;
            pad = app.Pad;
            
            %             horizPad = nan(size(rawIm,1),pad); % Column padding
            horizPad = inf(size(rawIm,1),pad); % Column padding
            imPad = [horizPad,rawIm,horizPad];
%             vertPad = nan(pad, size(imPad,2));% row Padding
            vertPad = inf(pad, size(imPad,2));% row Padding
            imPad = [vertPad;imPad;vertPad];
            
            imshow(imPad,'Parent',app.CallingApp.UIAxes);
            caxis(app.CallingApp.UIAxes, [0 app.CallingApp.caxisLim]);
            app.PadIm = imPad;
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            app.CallingApp.ReturnIm= app.PadIm;
            app.ImOut.Im = app.PadIm;
            
            UIFigureCloseRequest(app)
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PaddingWidthEditFieldLabel = uilabel(app.UIFigure);
            app.PaddingWidthEditFieldLabel.Text = 'Padding Width';
            app.PaddingWidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.PaddingWidthEditField.ValueChangedFcn = createCallbackFcn(app, @PaddingWidthEditFieldValueChanged, true);
            app.PadButton = uibutton(app.UIFigure, 'push');
            app.PadButton.ButtonPushedFcn = createCallbackFcn(app, @PadButtonPushed, true);
            app.PadButton.Text = 'Pad';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PaddingWindow_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end