classdef PupilTranslator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        LockTranslationButton    matlab.ui.control.Button
        PupilTrackpadPanel       matlab.ui.container.Panel
        UpButton                 matlab.ui.control.Button
        DownButton               matlab.ui.control.Button
        RightButton              matlab.ui.control.Button
        LeftButton               matlab.ui.control.Button
        SelectPupilListBoxLabel  matlab.ui.control.Label
        SelectPupilListBox       matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        CurrentPupilIm % smaller image showing just the 
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            
            % Decrement the Column value (move in -x)
            colVal = colVal -1;
            % Render the image
            
            
            % render the Image
            %{
            Image needs to have:
            - Dark background.
            - ID Pupils all over again
            - remask 'current' subPupil
            
            1. Choose pupil
            2. reverse mask IP_im
                - rest of image conserved while blacking out current Pupil
            3. Save the ~mask IP_Im
            -- ITERATE --
            4. Translate pupil
            5. Insert trans_pupil into ~mask
            6. imshow progress
            
            %}
            
            
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            switch key
                case 'a'
                    %go left!
                case 'd'
                    %go Right!
                case 'w'
                    %go up!
                case 's'
                    %go down!
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.LockTranslationButton = uibutton(app.UIFigure, 'push');
            app.LockTranslationButton.Text = 'Lock Translation';
            app.PupilTrackpadPanel = uipanel(app.UIFigure);
            app.PupilTrackpadPanel.Title = 'Pupil Trackpad';
            app.UpButton = uibutton(app.PupilTrackpadPanel, 'push');
            app.UpButton.Text = '^';
            app.DownButton = uibutton(app.PupilTrackpadPanel, 'push');
            app.DownButton.Text = 'v';
            app.RightButton = uibutton(app.PupilTrackpadPanel, 'push');
            app.RightButton.Text = '>';
            app.LeftButton = uibutton(app.PupilTrackpadPanel, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = '<';
            app.SelectPupilListBoxLabel = uilabel(app.UIFigure);
            app.SelectPupilListBoxLabel.Text = 'Select Pupil';
            app.SelectPupilListBox = uilistbox(app.UIFigure);
            app.SelectPupilListBox.Items = {'Pupil 1', 'Pupil 2', 'Pupil 3', 'Pupil 4', 'Pupil 5', 'Pupil 6', 'Pupil 7'};
            app.SelectPupilListBox.ItemsData = {'1', '2', '3', '4', '5', '6', '7'};
            app.SelectPupilListBox.Value = '1';
        end
    end

    methods (Access = public)

        % Construct app
        function app = PupilTranslator

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end